classdef GUI_brazo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MatrizTextArea               matlab.ui.control.TextArea
        MatrizTextAreaLabel          matlab.ui.control.Label
        SolucionconmatricesCheckBox  matlab.ui.control.CheckBox
        SoluciongeometricaCheckBox   matlab.ui.control.CheckBox
        AngulosLabel                 matlab.ui.control.Label
        LongitudesLabel              matlab.ui.control.Label
        Theta4EditField              matlab.ui.control.NumericEditField
        Theta4EditFieldLabel         matlab.ui.control.Label
        Theta3EditField              matlab.ui.control.NumericEditField
        Theta3EditFieldLabel         matlab.ui.control.Label
        Theta2EditField              matlab.ui.control.NumericEditField
        Theta2EditFieldLabel         matlab.ui.control.Label
        Theta1EditField              matlab.ui.control.NumericEditField
        Theta1EditFieldLabel         matlab.ui.control.Label
        L4EditField                  matlab.ui.control.NumericEditField
        L4EditFieldLabel             matlab.ui.control.Label
        L3EditField                  matlab.ui.control.NumericEditField
        L3EditFieldLabel             matlab.ui.control.Label
        L2EditField                  matlab.ui.control.NumericEditField
        L2EditFieldLabel             matlab.ui.control.Label
        L1EditField                  matlab.ui.control.NumericEditField
        L1EditFieldLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    R1
    R2
    R3
    R4 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SolucionconmatricesCheckBox
        function SolucionconmatricesCheckBoxValueChanged(app, event)
           
                if app.SolucionconmatricesCheckBox.Value == 1
                % 1. Obtenemos los ángulos actuales
                Th1 = deg2rad(app.Theta1EditField.Value);
                Th2 = deg2rad(app.Theta2EditField.Value);
                Th3 = deg2rad(app.Theta3EditField.Value);
                Th4 = deg2rad(app.Theta4EditField.Value);

                % 2. Calculamos las matrices de rotación
                app.R1 = [
                       cos(Th1) 0 sin(Th1)
                          0    1    0
                      -sin(Th1) 0 cos(Th1)];
                app.R2 = [cos(Th1 + Th2) -sin(Th1 + Th2) 0;
                       sin(Th1 + Th2)  cos(Th1 + Th2) 0;
                              0         0 1];
                app.R3 = [cos(Th2 + Th3) -sin(Th2 + Th3) 0;
                       sin(Th2 + Th3)  cos(Th2 + Th3) 0;
                              0         0 1];
             
                app.R4 = [cos(Th2 + Th3 + Th4) -sin(Th2 + Th3 + Th4) 0;
                       sin(Th2 + Th3 + Th4)  cos(Th2 + Th3 + Th4) 0;
                              0         0 1];


                % 3. Formateamos el texto con las 4 matrices
                texto = sprintf([ ...
                    'Matriz R1 (Y):\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n\n' ...
                    'Matriz R2 (Z):\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n\n' ...
                    'Matriz R3 (Z):\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n\n' ...
                    'Matriz R4 (Z):\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]\n[%.3f  %.3f  %.3f]'], ...
                    app.R1(1,1), app.R1(1,2), app.R1(1,3), app.R1(2,1), app.R1(2,2), app.R1(2,3), app.R1(3,1), app.R1(3,2), app.R1(3,3), ...
                    app.R2(1,1), app.R2(1,2), app.R2(1,3), app.R2(2,1), app.R2(2,2), app.R2(2,3), app.R2(3,1), app.R2(3,2), app.R2(3,3), ...
                    app.R3(1,1), app.R3(1,2), app.R3(1,3), app.R3(2,1), app.R3(2,2), app.R3(2,3), app.R3(3,1), app.R3(3,2), app.R3(3,3), ...
                    app.R4(1,1), app.R4(1,2), app.R4(1,3), app.R4(2,1), app.R4(2,2), app.R4(2,3), app.R4(3,1), app.R4(3,2), app.R4(3,3));

                % 4. Asignamos al TextArea correcto
                app.MatrizTextArea.Value = texto;
            else
                app.MatrizTextArea.Value = '';
                end


        end

        % Value changed function: SoluciongeometricaCheckBox
        function SoluciongeometricaCheckBoxValueChanged(app, event)

            
            if app.SoluciongeometricaCheckBox.Value == 1
                % Limpiar gráfica previa antes de dibujar
                cla(app.UIAxes);
            %valores introdudicidos
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;
            L3 = app.L3EditField.Value;
            L4 = app.L4EditField.Value;

            Th1 = app.Theta1EditField.Value;
            Th2 = app.Theta2EditField.Value;
            Th3 = app.Theta3EditField.Value;
            Th4 = app.Theta4EditField.Value;

            %grados a radianes
            Th1 = deg2rad(Th1);
            Th2 = deg2rad(Th2);
            Th3 = deg2rad(Th3);
            Th4 = deg2rad(Th4);

            %Dibujar los ejes
            line(app.UIAxes,[-5 10], [0 0], [0 0],'Color','blue','LineWidth',2);
            line(app.UIAxes,[0 0], [-5 10], [0 0],'Color', 'red', 'Linewidth',2);
            line(app.UIAxes,[0 0], [0 0], [-5 10],'Color', 'green', 'LineWidth',2);


% ESLABON 1


% Rotacion alrededor de Z
app.R1 = [cos(Th1) -sin(Th1) 0;
          sin(Th1)  cos(Th1) 0;
          0         0        1];

% Vector local del eslabon 1
P1 = [L1;
      0;
      0];

% Joint 1 SIEMPRE esta en el origen
joint1 = [0;
          0;
          0];

% Posicion del joint 2
p1_rotado = app.R1 * P1;
joint2 = joint1 + p1_rotado;


% ESLABON 2


% Rotacion LOCAL del segundo eslabon
R2local = [cos(Th2) -sin(Th2) 0;
           sin(Th2)  cos(Th2) 0;
           0         0        1];

% La orientacion de R2 depende de R1
app.R2 = app.R1 * R2local;

% Vector local del eslabon 2
P2 = [L2;
      0;
      0];

% Joint 3 parte desde joint 2
p2_rotado = app.R2 * P2;
joint3 = joint2 + p2_rotado;


% ESLABON 3


R3local = [cos(Th3) -sin(Th3) 0;
           sin(Th3)  cos(Th3) 0;
           0         0        1];

% R3 hereda la orientacion de R2
app.R3 = app.R2 * R3local;

P3 = [L3;
      0;
      0];

p3_rotado = app.R3 * P3;
joint4 = joint3 + p3_rotado;


% ESLABON 4


R4local = [cos(Th4) -sin(Th4) 0;
           sin(Th4)  cos(Th4) 0;
           0         0        1];

% R4 hereda la orientacion de R3
app.R4 = app.R3 * R4local;

P4 = [L4;
      0;
      0];

p4_rotado = app.R4 * P4;
joint5 = joint4 + p4_rotado;


% PUNTOS


scatter3(app.UIAxes, joint1(1), joint1(2), joint1(3), ...
    'filled', 'red');

scatter3(app.UIAxes, joint2(1), joint2(2), joint2(3), ...
    'filled', 'black');

scatter3(app.UIAxes, joint3(1), joint3(2), joint3(3), ...
    'filled', 'black');

scatter3(app.UIAxes, joint4(1), joint4(2), joint4(3), ...
    'filled', 'black');

scatter3(app.UIAxes, joint5(1), joint5(2), joint5(3), ...
    'filled', 'black');


             line(app.UIAxes, [joint1(1) joint2(1)], [joint1(2) joint2(2)], [joint1(3) joint2(3)], 'LineWidth', 2, 'Color', 'black');
             line(app.UIAxes,[joint2(1) joint3(1)], [joint2(2) joint3(2)], [joint2(3) joint3(3)], 'linewidth', 2, 'color', 'black');
             line(app.UIAxes,[joint3(1) joint4(1)], [joint3(2) joint4(2)], [joint3(3) joint4(3)], 'LineWidth',2, 'Color', 'black');
             line(app.UIAxes,[joint4(1) joint5(1)], [joint4(2) joint5(2)], [joint4(3) joint5(3)],'linewidth', 2, 'Color', 'black');
             hold on

             % Configuración de los ejes
                axis(app.UIAxes,'equal');
                grid(app.UIAxes,'on');
                box(app.UIAxes,'on');

                xlabel(app.UIAxes,'X');
                ylabel(app.UIAxes,'Y');
                zlabel(app.UIAxes,'Z');

                xlim(app.UIAxes,[-2 30]);
                ylim(app.UIAxes,[-2 30]);
                zlim(app.UIAxes,[-2 22]);

             % Vista 3D
                view(app.UIAxes,135,25);
                camproj(app.UIAxes,'orthographic');

             % Apariencia
                app.UIAxes.FontSize = 10;
                app.UIAxes.LineWidth = 1;
                  drawnow


             else

        app.MatrizTextArea.Value = '';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L4EditFieldLabel = uilabel(app.UIFigure);
            app.L4EditFieldLabel.Text = 'L4';
            app.L4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudesLabel = uilabel(app.UIFigure);
            app.LongitudesLabel.Text = 'Longitudes';
            app.AngulosLabel = uilabel(app.UIFigure);
            app.AngulosLabel.Text = 'Angulos';
            app.SoluciongeometricaCheckBox = uicheckbox(app.UIFigure);
            app.SoluciongeometricaCheckBox.ValueChangedFcn = createCallbackFcn(app, @SoluciongeometricaCheckBoxValueChanged, true);
            app.SoluciongeometricaCheckBox.Text = 'Solucion geometrica';
            app.SolucionconmatricesCheckBox = uicheckbox(app.UIFigure);
            app.SolucionconmatricesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SolucionconmatricesCheckBoxValueChanged, true);
            app.SolucionconmatricesCheckBox.Text = 'Solucion con matrices';
            app.MatrizTextAreaLabel = uilabel(app.UIFigure);
            app.MatrizTextAreaLabel.Text = 'Matriz';
            app.MatrizTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_brazo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end