classdef GUI_cubo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RxEditField                 matlab.ui.control.NumericEditField
        RxEditFieldLabel            matlab.ui.control.Label
        TransformarButton           matlab.ui.control.Button
        RzEditField                 matlab.ui.control.NumericEditField
        RzEditFieldLabel            matlab.ui.control.Label
        TzEditField                 matlab.ui.control.NumericEditField
        TzEditFieldLabel            matlab.ui.control.Label
        RyEditField                 matlab.ui.control.NumericEditField
        RyEditFieldLabel            matlab.ui.control.Label
        TyEditField                 matlab.ui.control.NumericEditField
        TyEditFieldLabel            matlab.ui.control.Label
        RotacionesLabel             matlab.ui.control.Label
        TraslacionesLabel           matlab.ui.control.Label
        TxEditField                 matlab.ui.control.NumericEditField
        TxEditFieldLabel            matlab.ui.control.Label
        TransformacindeunCuboLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransformarButton
        function TransformarButtonPushed(app, event)
            %Dimensiones
            ancho = 2;
            largo = 2;
            alto = 2;

            % valores introducidos por el usuario
            tx = app.TxEditField.Value;
            ty = app.TyEditField.Value;
            tz = app.TzEditField.Value;

            rx = app.RxEditField.Value;
            ry = app.RyEditField.Value;
            rz = app.RzEditField.Value;

            % grados a radianes

            rx = deg2rad(rx);
            ry = deg2rad(ry);
            rz = deg2rad(rz);

            %vertices y caras del cubo
            vertices = [
             0       0       0
             ancho   0       0
             ancho   largo   0
             0       largo   0
             0       0       alto
             ancho   0       alto
             ancho   largo   alto
             0       largo   alto
             ];

             caras = [
             1 2 3 4
             5 6 7 8
             1 2 6 5
             2 3 7 6
             3 4 8 7
             4 1 5 8
             ];

             %matricesw de rotacion
             Rx = [
                   1    0      0
                   0 cos(rx) -sin(rx)
                   0 sin(rx)  cos(rx)];

             Ry = [
                   cos(ry) 0 sin(ry)
                      0    1    0
                  -sin(ry) 0 cos(ry)];

             Rz = [
                   cos(rz) -sin(rz) 0
                   sin(rz)  cos(rz) 0
                      0        0    1];

             % combinar rotaciones
             R = Rx * Ry * Rz;


             % rotar los vertices
             verticesRotados = (R * vertices')';

             %aplicar traslacion
             verticesTras = verticesRotados + [tx ty tz];

             cla(app.UIAxes);

             %Dibujar los ejes
             line(app.UIAxes,[-5 10], [0 0], [0 0],'Color','blue','LineWidth',2);
             line(app.UIAxes,[0 0], [-5 10], [0 0],'Color', 'red', 'Linewidth',2);
             line(app.UIAxes,[0 0], [0 0], [-5 10],'Color', 'green', 'LineWidth',2);

             %Dibujar el cubo
             patch(app.UIAxes, ...
                'Vertices',verticesTras, ...
                'Faces',caras, ...
                'FaceColor','cyan', ...
                'FaceAlpha',0.4, ...
                'EdgeColor','black', ...
                'LineWidth',2);

            
             %Configurar grafica
             axis(app.UIAxes, 'equal');
             grid(app.UIAxes, 'on');
             view(app.UIAxes, 45, 30);
             xlabel(app.UIAxes, 'X');
             ylabel(app.UIAxes, 'Y');
             zlabel(app.UIAxes, 'Z');
             title(app.UIAxes, 'Cubo Transfromado')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TransformacindeunCuboLabel = uilabel(app.UIFigure);
            app.TransformacindeunCuboLabel.Text = 'Transformación de un Cubo';
            app.TxEditFieldLabel = uilabel(app.UIFigure);
            app.TxEditFieldLabel.Text = 'Tx';
            app.TxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TraslacionesLabel = uilabel(app.UIFigure);
            app.TraslacionesLabel.Text = 'Traslaciones';
            app.RotacionesLabel = uilabel(app.UIFigure);
            app.RotacionesLabel.Text = 'Rotaciones';
            app.TyEditFieldLabel = uilabel(app.UIFigure);
            app.TyEditFieldLabel.Text = 'Ty';
            app.TyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RyEditFieldLabel = uilabel(app.UIFigure);
            app.RyEditFieldLabel.Text = 'Ry';
            app.RyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TzEditFieldLabel = uilabel(app.UIFigure);
            app.TzEditFieldLabel.Text = 'Tz';
            app.TzEditField = uieditfield(app.UIFigure, 'numeric');
            app.RzEditFieldLabel = uilabel(app.UIFigure);
            app.RzEditFieldLabel.Text = 'Rz';
            app.RzEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransformarButton = uibutton(app.UIFigure, 'push');
            app.TransformarButton.ButtonPushedFcn = createCallbackFcn(app, @TransformarButtonPushed, true);
            app.TransformarButton.Text = 'Transformar';
            app.RxEditFieldLabel = uilabel(app.UIFigure);
            app.RxEditFieldLabel.Text = 'Rx';
            app.RxEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_cubo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end